classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        GenerateSignalsButton  matlab.ui.control.Button
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes3                matlab.ui.control.UIAxes
        UIAxes4                matlab.ui.control.UIAxes
        UIAxes5                matlab.ui.control.UIAxes
        MixSignalsButton       matlab.ui.control.Button
        CalculateDFTButton     matlab.ui.control.Button
    end


    properties (Access = private)
        varx = 0 : 1/100 : 10-1/100;
        vary1;
        vary2;
        vary3;
        varySum;
    end


    methods (Access = private)

        % Code that executes after component creation
        function onStart(app)
            
        end

        % Button pushed function: GenerateSignalsButton
        function btnPushed(app, event)
            app.vary1 = sin(2*pi*15*app.varx);
            app.vary2 = sin(2*pi*40*app.varx);
            %app.vary3 = sin(app.varx) + 3;
            plot(app.UIAxes, app.varx, app.vary1);
            plot(app.UIAxes2, app.varx, app.vary2);
            %plot(app.UIAxes3, app.varx, app.vary3);
        end

        % Button pushed function: MixSignalsButton
        function mixSignals(app, event)
            app.varySum = app.vary1 + app.vary2; % + app.vary3;
            plot(app.UIAxes4, app.varx, app.varySum);
        end

        % Button pushed function: CalculateDFTButton
        function calcDFT(app, event)
            y_dft = fft(app.varySum);
            m = abs(y_dft);
            f = (0:length(y_dft)-1)*100/length(y_dft);
            plot(app.UIAxes5, f, m);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Signal 1')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.GenerateSignalsButton = uibutton(app.UIFigure, 'push');
            app.GenerateSignalsButton.ButtonPushedFcn = createCallbackFcn(app, @btnPushed, true);
            app.GenerateSignalsButton.Text = 'Generate Signals';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Signal 2')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Signal 3')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Mixed Signal')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'DFT')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.MixSignalsButton = uibutton(app.UIFigure, 'push');
            app.MixSignalsButton.ButtonPushedFcn = createCallbackFcn(app, @mixSignals, true);
            app.MixSignalsButton.Text = 'Mix Signals';
            app.CalculateDFTButton = uibutton(app.UIFigure, 'push');
            app.CalculateDFTButton.ButtonPushedFcn = createCallbackFcn(app, @calcDFT, true);
            app.CalculateDFTButton.Text = 'Calculate DFT';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @onStart)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end