classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        PlotButton         matlab.ui.control.Button
        RhoEditFieldLabel  matlab.ui.control.Label
        RhoEditField       matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: PlotButton
        function plot(app, event)
            rho = app.RhoEditField.Value;
            u = 0 : pi / 32 : 2*pi; m = length(u);
            v = 0 : pi / 64 : pi; n = length(v);
            U = repmat(u, m, 1);
            V = repmat(v', 1, n);
            
            X = rho * cos(U) .* sin(V);
            Y = rho * sin(U) .* sin(V);
            Z = rho * cos(V);
            surf(app.UIAxes, X,Y,Z)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @plot, true);
            app.PlotButton.Text = 'Plot';
            app.RhoEditFieldLabel = uilabel(app.UIFigure);
            app.RhoEditFieldLabel.Text = 'Rho';
            app.RhoEditField = uieditfield(app.UIFigure, 'numeric');
            app.RhoEditField.Value = 3;
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end