classdef soundApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        UIAxes              matlab.ui.control.UIAxes
        PlayButton          matlab.ui.control.Button
        GainEditFieldLabel  matlab.ui.control.Label
        GainEditField       matlab.ui.control.EditField
        UIAxes2             matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Code that executes after component creation
        function onStart(app)
            clear, clc, close all;
            clear sound;
        end

        % Button pushed function: PlayButton
        function btnPlay(app, event)
            
            [y, Fs] = audioread('Chemicals in the Water Turn the Freaking Frogs Gay.wav');
            sound(y,Fs);
            
            yPlot = y(:,1);
            dt = 1/Fs;
            t = 0 : dt : (length(y)*dt)-dt;
            f = 1./t;
            plot(app.UIAxes, t, yPlot);
            
            
            [S,F,T] = spectrogram(yPlot);
            imagesc(app.UIAxes2, F, T, log(abs(S')));
        end

        % Close request function: UIFigure
        function onClose(app, event)
            delete(app)
            clear sound;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @onClose, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Frogs Gay')
            xlabel(app.UIAxes, 'TIme (s)')
            ylabel(app.UIAxes, 'Amplitude')
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @btnPlay, true);
            app.PlayButton.Text = 'Play';
            app.GainEditFieldLabel = uilabel(app.UIFigure);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.UIFigure, 'text');
            app.GainEditField.Value = '1';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Frogs Gay (Frequency)')
            xlabel(app.UIAxes2, 'Frequency')
            ylabel(app.UIAxes2, 'Amplitude')
        end
    end

    methods (Access = public)

        % Construct app
        function app = soundApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @onStart)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end